classdef Projeto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ReurnAtualPositionButton       matlab.ui.control.Button
        GetComauPositionButton         matlab.ui.control.Button
        SetAtualPositionButton         matlab.ui.control.Button
        StatusGraphLabel               matlab.ui.control.Label
        ComauLamp                      matlab.ui.control.Lamp
        ComauLampLabel                 matlab.ui.control.Label
        CoppeliaLamp                   matlab.ui.control.Lamp
        CoppeliaLampLabel              matlab.ui.control.Label
        TabGroup3                      matlab.ui.container.TabGroup
        CinemticaTab                   matlab.ui.container.Tab
        Comp_Cinematica                Comp_Cinematica
        ControleLinearTab              matlab.ui.container.Tab
        Comp_Controle                  Comp_Controle
        ConfiguracoesdeGraficoPanel_2  matlab.ui.container.Panel
        DisconnectComauButton          matlab.ui.control.Button
        LoadComauButton                matlab.ui.control.Button
        ConnectComauButton             matlab.ui.control.Button
        DisconnectCoppeliaButton       matlab.ui.control.Button
        LoadCoppeliaButton_2           matlab.ui.control.Button
        ConnectCoppeliaButton          matlab.ui.control.Button
        ConfiguracoesdeGraficoPanel    matlab.ui.container.Panel
        RepetirSeqButton               matlab.ui.control.Button
        ResetSeqButton                 matlab.ui.control.Button
        ControleSeqTamanhoLabel        matlab.ui.control.Label
        AtualizarButton                matlab.ui.control.Button
        SegundoVetorCoordDropDown      matlab.ui.control.DropDown
        SegundoVetorCoordLabel         matlab.ui.control.Label
        PresetPositionButton           matlab.ui.control.Button
        StandbyPositionButton          matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        JointTab                       matlab.ui.container.Tab
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Joint6Field                    matlab.ui.control.NumericEditField
        Joint6EditFieldLabel           matlab.ui.control.Label
        Joint5Field                    matlab.ui.control.NumericEditField
        Joint5EditFieldLabel           matlab.ui.control.Label
        Joint4Field                    matlab.ui.control.NumericEditField
        Joint4EditFieldLabel           matlab.ui.control.Label
        Joint3Field                    matlab.ui.control.NumericEditField
        Joint3EditFieldLabel           matlab.ui.control.Label
        Joint2Field                    matlab.ui.control.NumericEditField
        Join1Label                     matlab.ui.control.Label
        Joint1Field                    matlab.ui.control.NumericEditField
        Join1Label_2                   matlab.ui.control.Label
        PosTab                         matlab.ui.container.Tab
        mmLabel_3                      matlab.ui.control.Label
        mmLabel_2                      matlab.ui.control.Label
        mmLabel                        matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        YawField                       matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        PitchField                     matlab.ui.control.NumericEditField
        PitchEditFieldLabel            matlab.ui.control.Label
        RowField                       matlab.ui.control.NumericEditField
        RowEditFieldLabel              matlab.ui.control.Label
        ZField                         matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YField                         matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XField                         matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)


        AngleRadJ = [0 0 -pi/2 0 -pi/2 0];
        
        PosiX
        PosiY
        PosiZ
        Row
        Pitch
        Yaw

        RowPitYaw;
        Translacao;

        Metr2Mili = 100;
    end
    
    properties (Access = public)
        CoppComau % Description
        CommComau % Description
    end
    
    methods (Access = private)
        
        function results = OffEdit(app)
            app.RowField.Editable = 'off';
            app.PitchField.Editable = 'off';
            app.YawField.Editable = 'off';
            app.XField.Editable = 'off';
            app.YField.Editable = 'off';
            app.ZField.Editable = 'off';
            app.Joint6Field.Editable = 'off';
            app.Joint5Field.Editable = 'off';
            app.Joint4Field.Editable = 'off';
            app.Joint3Field.Editable = 'off';
            app.Joint2Field.Editable = 'off';
            app.Joint1Field.Editable = 'off';
        end
        
        function results = ArmCalculation(app, AngleJoint)
            Ang = 180/pi;
            
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;
            % Ponto inicial
            q0 = AngleJoint;
            app.Comp_Cinematica.q_Robot = q0;

            app.RowPitYaw = tr2rpy(robot.fkine(q0), 'deg');
            app.Translacao = transl(robot.fkine(q0));

%             R = SO3(robot.fkine(q0));
%             app.RowPitYaw = app.RowPitYaw*round(R.R);

            app.Comp_Cinematica.RPY = app.RowPitYaw;
            app.Comp_Cinematica.XYZ = app.Translacao;

            app.Row = app.RowPitYaw(1);
            app.RowField.Value = round(app.Row,2);
            app.Pitch = app.RowPitYaw(2);
            app.PitchField.Value = round(app.Pitch,2);
            app.Yaw = app.RowPitYaw(3);
            app.YawField.Value = round(app.Yaw,2);
            app.PosiX = app.Translacao(1);
            app.XField.Value = round(app.PosiX*1000,2);
            app.PosiY = app.Translacao(2);
            app.YField.Value = round(app.PosiY*1000,2);
            app.PosiZ = app.Translacao(3);
            app.ZField.Value = round(app.PosiZ*1000,2);

            app.Joint1Field.Value = round(Ang*AngleJoint(1),2);
            app.Joint2Field.Value = round(Ang*AngleJoint(2),2);
            app.Joint3Field.Value = round(Ang*AngleJoint(3),2);
            app.Joint4Field.Value = round(Ang*AngleJoint(4),2);
            app.Joint5Field.Value = round(Ang*AngleJoint(5),2);
            app.Joint6Field.Value = round(Ang*AngleJoint(6),2);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            OffEdit(app);
            ArmCalculation(app, app.AngleRadJ);
            app.Comp_Controle.q_Start = app.AngleRadJ;

            app.SegundoVetorCoordDropDown.ItemsData = [-1, 0, 1, 2, 3, 4, 5];
            app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:%d',app.Comp_Controle.Seq_Size);
            app.StatusGraphLabel.Text = sprintf('%s','Simulação: MATLAB');
            
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;
            % Ponto inicial
            q0 = app.AngleRadJ;
            
            % Configuração inicial do robô
            robot.plot(q0);
            
            ax = gca;
            fig = gcf;
            fig.Visible = 'off';
            copyobj(ax,app.Panel);
        end

        % Button pushed function: AtualizarButton
        function AtualizarButtonPushed(app, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');

            app.StatusGraphLabel.Text = sprintf('%s','Simulação: MATLAB');
            
            T0 = robot.base;
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;
            % Ponto inicial
            q0 = app.AngleRadJ;
            
            % Configuração inicial do robô
            delete(app.Panel.Children);
            robot.plot(q0);
            fig = gcf;
            fig.Visible = 'off';
            hold on;
            if app.SegundoVetorCoordDropDown.Value ~= -1
                TL = T0;
                
                if app.SegundoVetorCoordDropDown.Value > 0
                    TL = T;
                end

                for c = 1:app.SegundoVetorCoordDropDown.Value
                    TL = TL*L(c).A(q0(c));
                end
                TL.plot('rgb');
                
            end


            ax = gca;
            copyobj(ax,app.Panel);
            clf(fig);
           
        end

        % Button pushed function: StandbyPositionButton
        function StandbyPositionButtonPushed(app, event)
            app.AngleRadJ(1) = 0;
            app.AngleRadJ(2) = 0;
            app.AngleRadJ(3) = -pi/2;
            app.AngleRadJ(4) = 0;
            app.AngleRadJ(5) = -pi/2;
            app.AngleRadJ(6) = 0;

            ArmCalculation(app, app.AngleRadJ);
            app.AtualizarButtonPushed();
            app.Comp_Controle.q_Start = app.AngleRadJ;
        end

        % Button pushed function: PresetPositionButton
        function PresetPositionButtonPushed(app, event)
            Rad = pi/180;
            
            app.AngleRadJ(1) = app.Comp_Cinematica.PresetAng(1)*Rad;
            app.AngleRadJ(2) = app.Comp_Cinematica.PresetAng(2)*Rad;
            app.AngleRadJ(3) = app.Comp_Cinematica.PresetAng(3)*Rad;
            app.AngleRadJ(4) = app.Comp_Cinematica.PresetAng(4)*Rad;
            app.AngleRadJ(5) = app.Comp_Cinematica.PresetAng(5)*Rad;
            app.AngleRadJ(6) = app.Comp_Cinematica.PresetAng(6)*Rad;

            ArmCalculation(app, app.AngleRadJ);
            app.AtualizarButtonPushed();
        end

        % Callback function: Comp_Cinematica
        function Comp_CinematicaArmMoving(app, event)
            app.AngleRadJ(1) = app.Comp_Cinematica.q_Robot(1);
            app.AngleRadJ(2) = app.Comp_Cinematica.q_Robot(2);
            app.AngleRadJ(3) = app.Comp_Cinematica.q_Robot(3);
            app.AngleRadJ(4) = app.Comp_Cinematica.q_Robot(4);
            app.AngleRadJ(5) = app.Comp_Cinematica.q_Robot(5);
            app.AngleRadJ(6) = app.Comp_Cinematica.q_Robot(6);
            
            ArmCalculation(app, app.AngleRadJ);
            app.AtualizarButtonPushed();
            app.Comp_Controle.q_Start = app.AngleRadJ;
        end

        % Callback function: Comp_Controle
        function Seq_Movimento(app, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:%d',app.Comp_Controle.Seq_Size);
            app.StatusGraphLabel.Text = sprintf('%s','Simulação: MATLAB');
                
            q0 = app.Comp_Controle.q_Seq(:,(app.Comp_Controle.Seq_LastOp + 1))';
            plot(q0(:));
            robot.plot(q0);
            fig = gcf;
            fig.Visible = 'off';
            ArmCalculation(app, app.Comp_Controle.q_Seq(:,(app.Comp_Controle.Seq_LastOp + 1))');

            ax = gca;
            old_h1 = copyobj(ax,app.Panel);
            delete(app.Panel.Children);
            old_h2 = figure();

            for i=(app.Comp_Controle.Seq_LastOp + 2):app.Comp_Controle.Seq_Size
                delete(old_h2);
                
                % Ponto inicial
                q0 = app.Comp_Controle.q_Seq(:,i)';
                
                % Configuração inicial do robô
                
                robot.plot(q0);
                plot(q0(:));
                old_h2 = old_h1;
                old_h1 = copyobj(ax,app.Panel);
                ArmCalculation(app, app.Comp_Controle.q_Seq(:,i)');
                
            end
            delete(old_h1);
        end

        % Button pushed function: ConnectCoppeliaButton
        function ConnectCoppeliaButtonPushed(app, event)
            addpath('./lib/libCoppelia/');           
            
            %% Configuracoes iniciais
            
            % cria o objeto de comando do comau no coppeliasim
            app.CoppComau = ComauSim();
            
            % inicia a simulacao
            app.CoppComau.startSimulation();
            app.CoppeliaLamp.Color = 'g';
        end

        % Button pushed function: LoadCoppeliaButton_2
        function LoadCoppeliaButton_2Pushed(app, event)
            % delta t do controlador
            deltaT = 0.01;
            
            % Define a posicao atual do sm6 como o vetor inicial
            %q = deg2rad(q_i);
            if app.Comp_Controle.Seq_Size == 0
                msgbox("Faltando Sequência");
                return
            end

            q = app.Comp_Controle.q_Seq(:,1)';
            % envia o robo para a posicao inicial
            app.CoppComau.setJointTargetPosition(q);
            pause(2);

            total_points = size(app.Comp_Controle.q_Seq,2);
            for i=1:total_points
            
                fprintf('Enviando ponto: %d/%d \n', i, total_points);
            
                % envia target position para as juntas do sm6
                app.CoppComau.setJointTargetPosition(app.Comp_Controle.q_Seq(:,i)');
            
                % pequeno pause de acordo com o deltaT estipulado
                 pause(deltaT);
            end
            pause(5);
        end

        % Button pushed function: DisconnectCoppeliaButton
        function DisconnectCoppeliaButtonPushed(app, event)
            % finaliza a simulacao
            app.CoppComau.stopSimulation();
            app.CoppeliaLamp.Color = 'r';
        end

        % Button pushed function: ConnectComauButton
        function ConnectComauButtonPushed(app, event)
            app.CommComau = ComauComm('tcpip', '172.22.121.2', 4121);
            pause(3);
            
            app.ComauLamp.Color = 'g';
            
            % Limita a velocidade do robo
            comau.setArmOverride(50);
            
        end

        % Button pushed function: LoadComauButton
        function LoadComauButtonPushed(app, event)
            app.StatusGraphLabel.Text = sprintf('%s','Simulação: COMAU Smart Six');
            
            if app.Comp_Controle.Seq_Size == 0
                msgbox("Faltando Sequência");
                return
            end

            app.CommComau.setJointPositions(rad2deg(app.Comp_Controle.q_Seq(:,1)));

            %q_lei = app.CommComau.getJointPositions();

            %% Loop de envio das posicoes de juntas ao robo
            total_points = app.Comp_Controle.Seq_Size;
            for i=2:total_points
            
               fprintf('Enviando ponto: %d/%d \n', i, total_points);
            
               % Envia um conjunto de posicao para as juntas
               app.CommComau.setJointPositionsFly(rad2deg(app.Comp_Controle.Seq_Size(:,i)), true);
               
            end    

        end

        % Button pushed function: DisconnectComauButton
        function DisconnectComauButtonPushed(app, event)
            app.CommComau.close

            app.ComauLamp.Color = 'r';
        end

        % Button pushed function: ResetSeqButton
        function ResetSeqButtonPushed(app, event)
            app.Comp_Controle.Seq_Size = 0;
            app.Comp_Controle.Seq_LastOp = 0;
            app.Comp_Controle.q_Start = app.AngleRadJ;

            app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:%d',app.Comp_Controle.Seq_Size);
        end

        % Button pushed function: RepetirSeqButton
        function RepetirSeqButtonPushed(app, event)
            L(1) = Revolute ('alpha', pi/2, 'a', 0.15, 'd', -0.45);
            L(2) = Revolute ('alpha', pi, 'a', 0.59, 'offset', -pi/2);
            L(3) = Revolute ('alpha', -pi/2, 'a', 0.13, 'offset', pi/2);
            L(4) = Revolute ('alpha', -pi/2, 'a', 0, 'd', -0.6471);
            L(5) = Revolute ('alpha', pi/2);
            L(6) = Revolute ('alpha', pi, 'a', -0.095, 'offset', pi);
            % Concatenação das juntas
            robot = SerialLink(L, 'name', 'COMAU Smart Six');
            
            T = SE3([1 0 0 0 ; 0 -1 0 0 ; 0 0 -1 0 ; 0 0 0 1]);
            robot.base = T;

            app.ControleSeqTamanhoLabel.Text = sprintf('Ctrl. Seq. Tamanho:%d',app.Comp_Controle.Seq_Size);
            app.StatusGraphLabel.Text = sprintf('%s','Simulação: MATLAB');
                
            q0 = app.Comp_Controle.q_Seq(:,1)';
            robot.plot(q0);
            fig = gcf;
            fig.Visible = 'off';
            ArmCalculation(app, app.Comp_Controle.q_Seq(:,1)');

            ax = gca;
            old_h1 = copyobj(ax,app.Panel);
            delete(app.Panel.Children);
            old_h2 = figure();

            for i= 2:app.Comp_Controle.Seq_Size
                delete(old_h2);
                
                % Ponto inicial
                q0 = app.Comp_Controle.q_Seq(:,i)';
                
                % Configuração inicial do robô
                
                robot.plot(q0);
                old_h2 = old_h1;
                old_h1 = copyobj(ax,app.Panel);
                ArmCalculation(app, app.Comp_Controle.q_Seq(:,i)');
                
            end
            delete(old_h1);
        end

        % Button pushed function: SetAtualPositionButton
        function SetAtualPositionButtonPushed(app, event)
            app.AngleRadJ = app.Comp_Controle.q_Seq(:,end)';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.JointTab = uitab(app.TabGroup2);
            app.JointTab.Title = 'Joint';
            app.Join1Label_2 = uilabel(app.JointTab);
            app.Join1Label_2.Text = 'Joint 1';
            app.Joint1Field = uieditfield(app.JointTab, 'numeric');
            app.Join1Label = uilabel(app.JointTab);
            app.Join1Label.Text = 'Joint 2';
            app.Joint2Field = uieditfield(app.JointTab, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.JointTab);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3Field = uieditfield(app.JointTab, 'numeric');
            app.Joint4EditFieldLabel = uilabel(app.JointTab);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4Field = uieditfield(app.JointTab, 'numeric');
            app.Joint5EditFieldLabel = uilabel(app.JointTab);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5Field = uieditfield(app.JointTab, 'numeric');
            app.Joint6EditFieldLabel = uilabel(app.JointTab);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6Field = uieditfield(app.JointTab, 'numeric');
            app.Label = uilabel(app.JointTab);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.JointTab);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.JointTab);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.JointTab);
            app.Label_4.Text = '°';
            app.Label_5 = uilabel(app.JointTab);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.JointTab);
            app.Label_6.Text = '°';
            app.PosTab = uitab(app.TabGroup2);
            app.PosTab.Title = 'Pos.';
            app.XEditFieldLabel = uilabel(app.PosTab);
            app.XEditFieldLabel.Text = 'X';
            app.XField = uieditfield(app.PosTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.PosTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YField = uieditfield(app.PosTab, 'numeric');
            app.ZEditFieldLabel = uilabel(app.PosTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZField = uieditfield(app.PosTab, 'numeric');
            app.RowEditFieldLabel = uilabel(app.PosTab);
            app.RowEditFieldLabel.Text = 'Row';
            app.RowField = uieditfield(app.PosTab, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.PosTab);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchField = uieditfield(app.PosTab, 'numeric');
            app.YawEditFieldLabel = uilabel(app.PosTab);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawField = uieditfield(app.PosTab, 'numeric');
            app.Label_7 = uilabel(app.PosTab);
            app.Label_7.Text = '°';
            app.Label_8 = uilabel(app.PosTab);
            app.Label_8.Text = '°';
            app.Label_9 = uilabel(app.PosTab);
            app.Label_9.Text = '°';
            app.mmLabel = uilabel(app.PosTab);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.PosTab);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel_3 = uilabel(app.PosTab);
            app.mmLabel_3.Text = 'mm';
            app.Panel = uipanel(app.UIFigure);
            app.StandbyPositionButton = uibutton(app.UIFigure, 'push');
            app.StandbyPositionButton.ButtonPushedFcn = createCallbackFcn(app, @StandbyPositionButtonPushed, true);
            app.StandbyPositionButton.Text = 'Standby Position';
            app.PresetPositionButton = uibutton(app.UIFigure, 'push');
            app.PresetPositionButton.ButtonPushedFcn = createCallbackFcn(app, @PresetPositionButtonPushed, true);
            app.PresetPositionButton.Text = 'Preset Position';
            app.ConfiguracoesdeGraficoPanel = uipanel(app.UIFigure);
            app.ConfiguracoesdeGraficoPanel.Title = 'Gráfico';
            app.SegundoVetorCoordLabel = uilabel(app.ConfiguracoesdeGraficoPanel);
            app.SegundoVetorCoordLabel.Text = 'Segundo Vetor Coord.:';
            app.SegundoVetorCoordDropDown = uidropdown(app.ConfiguracoesdeGraficoPanel);
            app.SegundoVetorCoordDropDown.Items = {'Nenhum', 'Base', 'Link 1', 'Link 2', 'Link 3', 'Link 4', 'Link 5'};
            app.SegundoVetorCoordDropDown.Value = 'Nenhum';
            app.AtualizarButton = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.AtualizarButton.ButtonPushedFcn = createCallbackFcn(app, @AtualizarButtonPushed, true);
            app.AtualizarButton.Text = 'Atualizar';
            app.ControleSeqTamanhoLabel = uilabel(app.ConfiguracoesdeGraficoPanel);
            app.ControleSeqTamanhoLabel.Text = 'Controle Seq Tamanho:';
            app.ResetSeqButton = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.ResetSeqButton.ButtonPushedFcn = createCallbackFcn(app, @ResetSeqButtonPushed, true);
            app.ResetSeqButton.Text = 'Reset Seq';
            app.RepetirSeqButton = uibutton(app.ConfiguracoesdeGraficoPanel, 'push');
            app.RepetirSeqButton.ButtonPushedFcn = createCallbackFcn(app, @RepetirSeqButtonPushed, true);
            app.RepetirSeqButton.Text = 'Repetir Seq';
            app.ConfiguracoesdeGraficoPanel_2 = uipanel(app.UIFigure);
            app.ConfiguracoesdeGraficoPanel_2.Title = 'Braço';
            app.ConnectCoppeliaButton = uibutton(app.ConfiguracoesdeGraficoPanel_2, 'push');
            app.ConnectCoppeliaButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectCoppeliaButtonPushed, true);
            app.ConnectCoppeliaButton.Text = 'Connect Coppelia';
            app.LoadCoppeliaButton_2 = uibutton(app.ConfiguracoesdeGraficoPanel_2, 'push');
            app.LoadCoppeliaButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadCoppeliaButton_2Pushed, true);
            app.LoadCoppeliaButton_2.Text = 'Load Coppelia';
            app.DisconnectCoppeliaButton = uibutton(app.ConfiguracoesdeGraficoPanel_2, 'push');
            app.DisconnectCoppeliaButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectCoppeliaButtonPushed, true);
            app.DisconnectCoppeliaButton.Text = 'Disconnect Coppelia';
            app.ConnectComauButton = uibutton(app.ConfiguracoesdeGraficoPanel_2, 'push');
            app.ConnectComauButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectComauButtonPushed, true);
            app.ConnectComauButton.Text = 'Connect Comau';
            app.LoadComauButton = uibutton(app.ConfiguracoesdeGraficoPanel_2, 'push');
            app.LoadComauButton.ButtonPushedFcn = createCallbackFcn(app, @LoadComauButtonPushed, true);
            app.LoadComauButton.Text = 'Load Comau';
            app.DisconnectComauButton = uibutton(app.ConfiguracoesdeGraficoPanel_2, 'push');
            app.DisconnectComauButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectComauButtonPushed, true);
            app.DisconnectComauButton.Text = 'Disconnect Comau';
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.CinemticaTab = uitab(app.TabGroup3);
            app.CinemticaTab.Title = 'Cinemática';
            app.Comp_Cinematica = Comp_Cinematica(app.CinemticaTab);
            app.Comp_Cinematica.ArmMovingFcn = createCallbackFcn(app, @Comp_CinematicaArmMoving, true);
            app.ControleLinearTab = uitab(app.TabGroup3);
            app.ControleLinearTab.Title = 'Controle Linear';
            app.Comp_Controle = Comp_Controle(app.ControleLinearTab);
            app.Comp_Controle.Seq_MovimentoFcn = createCallbackFcn(app, @Seq_Movimento, true);
            app.CoppeliaLampLabel = uilabel(app.UIFigure);
            app.CoppeliaLampLabel.Text = 'Coppelia';
            app.CoppeliaLamp = uilamp(app.UIFigure);
            app.ComauLampLabel = uilabel(app.UIFigure);
            app.ComauLampLabel.Text = 'Comau';
            app.ComauLamp = uilamp(app.UIFigure);
            app.StatusGraphLabel = uilabel(app.UIFigure);
            app.StatusGraphLabel.Text = 'Status: -Graph';
            app.SetAtualPositionButton = uibutton(app.UIFigure, 'push');
            app.SetAtualPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SetAtualPositionButtonPushed, true);
            app.SetAtualPositionButton.Text = 'Set Atual Position';
            app.GetComauPositionButton = uibutton(app.UIFigure, 'push');
            app.GetComauPositionButton.Text = 'Get Comau Position';
            app.ReurnAtualPositionButton = uibutton(app.UIFigure, 'push');
            app.ReurnAtualPositionButton.Text = 'Reurn Atual Position';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Projeto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end